classdef smartparking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        ENTERSwitchLabel             matlab.ui.control.Label
        ENTERSwitch                  matlab.ui.control.RockerSwitch
        EXITSwitchLabel              matlab.ui.control.Label
        EXITSwitch                   matlab.ui.control.RockerSwitch
        YELLOWLamp                   matlab.ui.control.Lamp
        REDLamp                      matlab.ui.control.Lamp
        GREENLamp                    matlab.ui.control.Lamp
        SMARTPARKINGSYSTEMLabel      matlab.ui.control.Label
        SPOTAVAILABLEEditFieldLabel  matlab.ui.control.Label
        SPOTAVAILABLEEditField       matlab.ui.control.NumericEditField
        STARTButton                  matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        KnobLabel                    matlab.ui.control.Label
        Knob                         matlab.ui.control.Knob
        DateDatePickerLabel          matlab.ui.control.Label
        DateDatePicker               matlab.ui.control.DatePicker
    end

    
    properties (Access = private)
        a; % arduino
        s; %servo
        c=14;%no of parking spots
        p={'p14.JPEG','p13.JPEG','p12.JPEG','p11.JPEG','p10.JPEG','p9.JPEG','p8.JPEG','p7.JPEG','p6.JPEG','p5.JPEG','p4.JPEG','p3.JPEG','p2.JPEG','p1.JPEG','p0.jpg'}; % Defining photos in an array to display it 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a=arduino(); %code for reading arduino
            app.s=servo(app.a,'D10');%code for reading servo on arduino digital port
            writePosition(app.s,0)%restricting servo to not move when the app opens
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.SPOTAVAILABLEEditField.Value=app.c;%displaying number of parking spots available in counter
            app.EditField.Value='WELCOME!!';
            app.DateDatePicker.Value=datetime('today');%displaying today's date
            writeDigitalPin(app.a,'D7',1);%code to turn on red LED on breadboard
            app.Image.ImageSource='p0.jpg';%displaying empty parking lot pic on App
            app.REDLamp.Color='red';% code to turn ON red LED in App
            app.YELLOWLamp.Color='red';
            app.GREENLamp.Color='red';
        end

        % Value changed function: ENTERSwitch
        function ENTERSwitchValueChanged(app, event)
        if app.c>0 %preventing enter button from operating when counter goes negative
            app.REDLamp.Color='green';
                app.YELLOWLamp.Color='green';
                app.GREENLamp.Color='green';
                writeDigitalPin(app.a,'D7',0);
                  writeDigitalPin(app.a,'D6',1);
                  writePosition(app.s,1);% gate open
                for i=1:7
                    app.Image.ImageSource=char(app.p(app.c+1));pause(0.2);%defining photos in array inorder to display the blinking of car when entering the parking lot
                    app.Image.ImageSource=char(app.p(app.c));
                    app.Image.ImageSource=char(app.p(app.c+1));pause(0.2);
                    app.Image.ImageSource=char(app.p(app.c));
                    app.Image.ImageSource=char(app.p(app.c+1));pause(0.2);
                    app.Image.ImageSource=char(app.p(app.c));
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';pause(0.3)% defining lamp in loop for blinking purpose
                    app.REDLamp.Enable='off';app.GREENLamp.Enable='off';app.YELLOWLamp.Enable='off';pause(0.3)
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';
                end
                  pause(5);
                  app.REDLamp.Color='red';
                app.YELLOWLamp.Color='red';
                app.GREENLamp.Color='red';
                writeDigitalPin(app.a,'D6',0);
                  writeDigitalPin(app.a,'D7',1);
                  for i=1:7
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';pause(0.3)
                    app.REDLamp.Enable='off';app.GREENLamp.Enable='off';app.YELLOWLamp.Enable='off';pause(0.3)
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';
                  end
                  writePosition(app.s,0);%gate closes
                  app.c=app.c-1;%number of available parking spots reduced by 1
                   thingSpeakWrite(1109716,app.c,'WriteKey','CUCXJH6YMTE1NWWJ');% the available spots will be updated to ThingSpeak platform
            app.SPOTAVAILABLEEditField.Value=app.c;% displaying the available spots in the App 
        end
        if app.c==0 % defining the condition when the counter becomes zero, display Parking Full
           app.EditField.Value='PARKING FULL PLEASE COMEBACK LATER!!';
           app.REDLamp.Color='yellow';% turning 'ON' YELLOW LED in App
           app.YELLOWLamp.Color='yellow';
           app.GREENLamp.Color='yellow';
           writeDigitalPin(app.a,'D5',1)% turning 'ON' YELLOW LED on breadboard
           writeDigitalPin(app.a,'D7',0)
        else 
             app.EditField.Value='WELCOME!!'; 
        end
        if app.c<0 % this is for backup plan, if somwhow due to bug or some other issue, the counter goes negative. it wil assign it zero.
            app.c=0;
        end
        end

        % Value changed function: EXITSwitch
        function EXITSwitchValueChanged(app, event)
        if app.c<14 %this condition is defined so it will limit EXIT button to only work till the available spot is less than 14.
            app.c=app.c+1; %available spots increased by 1.
            thingSpeakWrite(1109716,app.c,'WriteKey','CUCXJH6YMTE1NWWJ');% available spots updated on ThingSpeak
            app.SPOTAVAILABLEEditField.Value=app.c; %displaying available spots in App
                app.REDLamp.Color='green';
                app.YELLOWLamp.Color='green';
                app.GREENLamp.Color='green';
                 writeDigitalPin(app.a,'D7',0);
                  writeDigitalPin(app.a,'D6',1);
                  writePosition(app.s,1);
                  for i=1:7
                    app.Image.ImageSource=char(app.p(app.c));pause(0.2);%defining photos in array inorder to display the blinking of car when exiting the parking lot
                    app.Image.ImageSource=char(app.p(app.c+1));
                    app.Image.ImageSource=char(app.p(app.c));pause(0.2);
                    app.Image.ImageSource=char(app.p(app.c+1));
                    app.Image.ImageSource=char(app.p(app.c));pause(0.2);
                    app.Image.ImageSource=char(app.p(app.c+1));
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';pause(0.3)
                    app.REDLamp.Enable='off';app.GREENLamp.Enable='off';app.YELLOWLamp.Enable='off';pause(0.3)
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';
                  end
                  pause(5);
                  writeDigitalPin(app.a,'D6',0);
                  writeDigitalPin(app.a,'D7',1);
                app.REDLamp.Color='red';
                app.YELLOWLamp.Color='red';
                app.GREENLamp.Color='red';
                  for i=1:7
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';pause(0.3)
                    app.REDLamp.Enable='off';app.GREENLamp.Enable='off';app.YELLOWLamp.Enable='off';pause(0.3)
                    app.REDLamp.Enable='on';app.GREENLamp.Enable='on';app.YELLOWLamp.Enable='on';
                  end
                  writePosition(app.s,0);
            if app.c>=0
                app.EditField.Value='WELCOME!!';
            end
                if app.c>14
                    app.c=0;
                end
        end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;% assigning the knob value 
             writePosition(app.s,value/100)% we divide the value by 100, as the servo only understands 0 and 1.
        app.REDLamp.Color='yellow';
           app.YELLOWLamp.Color='yellow';
           app.GREENLamp.Color='yellow';
           writeDigitalPin(app.a,'D5',1)
            writeDigitalPin(app.a,'D7',0)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.ENTERSwitchLabel = uilabel(app.UIFigure);
            app.ENTERSwitchLabel.Text = 'ENTER';
            app.ENTERSwitch = uiswitch(app.UIFigure, 'rocker');
            app.ENTERSwitch.Items = {'', 'On'};
            app.ENTERSwitch.ValueChangedFcn = createCallbackFcn(app, @ENTERSwitchValueChanged, true);
            app.ENTERSwitch.Value = '';
            app.EXITSwitchLabel = uilabel(app.UIFigure);
            app.EXITSwitchLabel.Text = 'EXIT';
            app.EXITSwitch = uiswitch(app.UIFigure, 'rocker');
            app.EXITSwitch.Items = {'', 'On'};
            app.EXITSwitch.ValueChangedFcn = createCallbackFcn(app, @EXITSwitchValueChanged, true);
            app.EXITSwitch.Value = '';
            app.YELLOWLamp = uilamp(app.UIFigure);
            app.REDLamp = uilamp(app.UIFigure);
            app.GREENLamp = uilamp(app.UIFigure);
            app.SMARTPARKINGSYSTEMLabel = uilabel(app.UIFigure);
            app.SMARTPARKINGSYSTEMLabel.Text = 'SMART PARKING SYSTEM';
            app.SPOTAVAILABLEEditFieldLabel = uilabel(app.UIFigure);
            app.SPOTAVAILABLEEditFieldLabel.Text = 'SPOT AVAILABLE';
            app.SPOTAVAILABLEEditField = uieditfield(app.UIFigure, 'numeric');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smartparking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end